classdef DragBudget_Calculator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PowerBudgetCalculatorLabel      matlab.ui.control.Label
        CalculateButton                 matlab.ui.control.Button
        AvailablepowerkWEditFieldLabel  matlab.ui.control.Label
        AvailablepowerkWEditField       matlab.ui.control.NumericEditField
        PowerfromtheenginekWEditField_2Label  matlab.ui.control.Label
        PowerfromtheenginekWEditField_2  matlab.ui.control.NumericEditField
        CarMasskgEditFieldLabel         matlab.ui.control.Label
        CarMasskgEditField              matlab.ui.control.NumericEditField
        Frontalaream2EditFieldLabel     matlab.ui.control.Label
        Frontalaream2EditField          matlab.ui.control.NumericEditField
        CdEditFieldLabel                matlab.ui.control.Label
        CdEditField                     matlab.ui.control.NumericEditField
        CoefficientofRollingresistanceEditFieldLabel  matlab.ui.control.Label
        CoefficientofRollingresistanceEditField  matlab.ui.control.NumericEditField
        AirDensitykgm3EditField_2Label  matlab.ui.control.Label
        AirDensitykgm3EditField_2       matlab.ui.control.NumericEditField
        TargetedTimefortheaccelerationeventsLabel  matlab.ui.control.Label
        TargetedTimefortheaccelerationeventsEditField  matlab.ui.control.NumericEditField
        NITKRacingAerodynamicsLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            power=app.PowerfromtheenginekWEditField_2.Value;
            mass=app.CarMasskgEditField.Value;
            crr=app.CoefficientofRollingresistanceEditField.Value;
            rho=app.AirDensitykgm3EditField_2.Value;
            t=app.TargetedTimefortheaccelerationeventsEditField.Value;
            A=app.Frontalaream2EditField.Value;
            cd=app.CdEditField.Value;
            % Rolling resistance
            g=9.81;
            rolling=mass * g * crr;
            %s=ut+1/2at2
            s=75;
            a1=2*s;
            a2=t*t;
            a=a1/a2;
            %v=u+at
            vel=a*t;
            
            %car power
            car_power=mass * a * vel ;
            
            %drag power
            dpower=0.5 * rho * cd * A * (vel * vel * vel) ;
            z=dpower + car_power + rolling;
            q=z/1000;
            app.AvailablepowerkWEditField.Value= power-q;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.PowerBudgetCalculatorLabel = uilabel(app.UIFigure);
            app.PowerBudgetCalculatorLabel.Text = 'Power Budget Calculator';
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.AvailablepowerkWEditFieldLabel = uilabel(app.UIFigure);
            app.AvailablepowerkWEditFieldLabel.Text = 'Available power(kW)';
            app.AvailablepowerkWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PowerfromtheenginekWEditField_2Label = uilabel(app.UIFigure);
            app.PowerfromtheenginekWEditField_2Label.Text = 'Power from the engine (kW)';
            app.PowerfromtheenginekWEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.CarMasskgEditFieldLabel = uilabel(app.UIFigure);
            app.CarMasskgEditFieldLabel.Text = ' Car Mass (kg)';
            app.CarMasskgEditField = uieditfield(app.UIFigure, 'numeric');
            app.Frontalaream2EditFieldLabel = uilabel(app.UIFigure);
            app.Frontalaream2EditFieldLabel.Text = 'Frontal area(m2)';
            app.Frontalaream2EditField = uieditfield(app.UIFigure, 'numeric');
            app.CdEditFieldLabel = uilabel(app.UIFigure);
            app.CdEditFieldLabel.Text = 'Cd';
            app.CdEditField = uieditfield(app.UIFigure, 'numeric');
            app.CoefficientofRollingresistanceEditFieldLabel = uilabel(app.UIFigure);
            app.CoefficientofRollingresistanceEditFieldLabel.Text = 'Coefficient of Rolling resistance';
            app.CoefficientofRollingresistanceEditField = uieditfield(app.UIFigure, 'numeric');
            app.AirDensitykgm3EditField_2Label = uilabel(app.UIFigure);
            app.AirDensitykgm3EditField_2Label.Text = 'Air Density (kg/m3) ';
            app.AirDensitykgm3EditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.TargetedTimefortheaccelerationeventsLabel = uilabel(app.UIFigure);
            app.TargetedTimefortheaccelerationeventsLabel.Text = {'Targeted Time for the '; 'acceleration event (s)'};
            app.TargetedTimefortheaccelerationeventsEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetedTimefortheaccelerationeventsEditField.Limits = [0 10];
            app.NITKRacingAerodynamicsLabel = uilabel(app.UIFigure);
            app.NITKRacingAerodynamicsLabel.Text = 'NITKRacing Aerodynamics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DragBudget_Calculator

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end